classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        liveGroundTrackSwitch           matlab.ui.control.Switch
        liveGroundTrackSwitchLabel      matlab.ui.control.Label
        DSwitch                         matlab.ui.control.Switch
        DSwitchLabel                    matlab.ui.control.Label
        NOOrbitsEditField_2             matlab.ui.control.NumericEditField
        NOOrbitsEditField_2Label        matlab.ui.control.Label
        InclinationEditField_2          matlab.ui.control.NumericEditField
        InclinationEditField_2Label     matlab.ui.control.Label
        RAANEditField_2                 matlab.ui.control.NumericEditField
        RAANEditField_2Label            matlab.ui.control.Label
        Init_tureAnomalyEditField_2     matlab.ui.control.NumericEditField
        Init_tureAnomalyEditField_2Label  matlab.ui.control.Label
        EccentrcityEditField_2          matlab.ui.control.NumericEditField
        EccentrcityEditField_2Label     matlab.ui.control.Label
        AOPEditField_2                  matlab.ui.control.NumericEditField
        AOPEditField_2Label             matlab.ui.control.Label
        SemimajorEditField_2            matlab.ui.control.NumericEditField
        SemimajorEditField_2Label       matlab.ui.control.Label
        PreSelectedOrbitsDropDown       matlab.ui.control.DropDown
        PreSelectedOrbitsDropDownLabel  matlab.ui.control.Label
        PLOTButton                      matlab.ui.control.Button
        StopButton                      matlab.ui.control.Button
    end

    
    properties (Access = private)
        three_d double% Description
        live double
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            
        end

        % Button pushed function: PLOTButton
        function PLOTButtonPushed(app, event)
            close all
%             select=app.PreSelectedOrbitsDropDown.Value;
%             switch select
%                 case "Custom"
%                     app.AOPEditField_2.Editable="off";
%             end
%% COEs
    semi_major= app.SemimajorEditField_2.Value;
%     app.EditField.Value=semi_major;
    inclination_int= app.InclinationEditField_2.Value;
    ecc= app.EccentrcityEditField_2.Value; 
    RAAN=app.RAANEditField_2.Value;
    AOP= app.AOPEditField_2.Value;
    n_orbits= app.NOOrbitsEditField_2.Value;
    nu_0= app.Init_tureAnomalyEditField_2.Value;

%% Constants
       mu=3.986004418e5; % km^3/s^2
       RE=6371;% km
       period=2*pi*sqrt(semi_major^3/mu);
       we=2*pi/24/3600;%(2*pi/86164);%(2*pi+2*pi/365.26)/(24*3600);
   %Convert Degree 2 radian
       inclination_int=deg2rad(inclination_int);
       RAAN=deg2rad(RAAN);
       AOP=deg2rad(AOP);
       nu_0=deg2rad(nu_0);
%J2 effect 'earth Oblateness'
       J2=1.08262668e-3;
       fac=-3/2*sqrt(mu)*J2*RE^2/(1-ecc^2)^2/semi_major^(7/2);
       nodal_regression=fac*cos(inclination_int);                   % ' dRAAN/dt '
       perigee_rotation=fac*(5/2*sin(inclination_int)^2-2);         % ' dAOP/dt '
   %% Function handles
       nu=@(E) 2*atan(tan(E/2)*sqrt((1+ecc)/(1-ecc)));
       Eccentric_anomaly=@(nu) (2*atan(tan(nu/2)*sqrt((1-ecc)/(1+ecc))));
       mean_anomaly=@(t) 2*pi*t/period;
       Kepler_eqn=@(E) E-ecc*sin(E);
   %% Initial and final time
        E_0=Eccentric_anomaly(nu_0);
        if(nu_0>pi)
        mean_anomaly_0=Kepler_eqn(E_0)+2*pi;
        else
             mean_anomaly_0=Kepler_eqn(E_0);
        end
        Init_time=mean_anomaly_0*period/(2*pi);
        final_time=n_orbits*period+Init_time;
        time_domain=linspace(Init_time,final_time,10000);
        %% Initialize Plot variables
        Ecc_anomaly=nan(1,length(time_domain));
        True_anomaly=nan(1,length(time_domain));
        r=nan(1,length(time_domain));
        longitude=nan(1,length(time_domain));
        latitude=nan(1,length(time_domain));
        theta=nan(1,length(time_domain));
        R=[];
%% Time loop
        for t=1:length(time_domain) 
                time=time_domain(t);
                Kepler_eqn_iter=@(E) E-ecc*sin(E) - mean_anomaly(time);
                Ecc_anomaly(t)=fzero(Kepler_eqn_iter,E_0);
                E_0=Ecc_anomaly(t); %Updating the inintial condition
        end
        True_anomaly=nu(Ecc_anomaly);
        r=(semi_major.*(1-ecc.^2))./(1+ecc*cos(True_anomaly));
        coord(1,:)=r.*cos(True_anomaly);
        coord(2,:)=r.*sin(True_anomaly);
        coord(3,:)=0;
        RAAN_new=nodal_regression*(time_domain-Init_time)+RAAN;
        AOP_new=perigee_rotation*(time_domain-Init_time)+AOP;
        theta=we*(time_domain-Init_time);
        %Coordinates with J2 effect
        X=sum([-sin(RAAN_new).*cos(inclination_int).*sin(AOP_new)+cos(RAAN_new).*cos(AOP_new);-sin(RAAN_new).*cos(inclination_int).*cos(AOP_new)-cos(RAAN_new).*sin(AOP_new);sin(RAAN_new).*sin(inclination_int)].*coord,1);
        Y=sum([cos(RAAN_new).*cos(inclination_int).*sin(AOP_new)+sin(RAAN_new).*cos(AOP_new);cos(RAAN_new).*cos(inclination_int).*cos(AOP_new)-sin(RAAN_new).*sin(AOP_new);-cos(RAAN_new).*sin(AOP_new)].*coord,1);
        Z=sum([sin(inclination_int).*sin(AOP_new);sin(inclination_int).*cos(AOP_new);repelem(cos(inclination_int),length(time_domain))].*coord,1);
        X_earth_rot=sum([cos(theta);sin(theta);zeros(1,length(time_domain))].*[X;Y;Z],1);
        Y_earth_rot=sum([-sin(theta);cos(theta);zeros(1,length(time_domain))].*[X;Y;Z],1);
        Z_earth_rot=sum([zeros(1,length(time_domain));zeros(1,length(time_domain)); ones(1,length(time_domain))].*[X;Y;Z],1);
        z=vecnorm([X_earth_rot;Y_earth_rot;Z_earth_rot]);
        l=X_earth_rot./z;
        m=Y_earth_rot./z;
        n=Z_earth_rot./z;
        latitude=asind(n);
        q=m>0;
        longitude=acosd(l./cosd(latitude));
        longitude(~q)=-acosd(l(~q)./cosd(latitude(~q)));
        z=ones(40);

        %% Plotting
        a1=app.DSwitch.Value;
        a2=app.liveGroundTrackSwitch.Value;
        if(a1=="On")
        figure('Color','K')
        world_map=imread("land_ocean_ice_2048.jpg","jpg");
        ax=axes('XLim',[-2*semi_major 2*semi_major],'YLim',[-2*semi_major 2*semi_major],'ZLim',[-2*semi_major 2*semi_major],'Color','K');
        [x,y,z]=ellipsoid(0,0,0,RE,RE,RE,50);
        hold on;
        earth=surface(x,y,-z,'FaceColor','texturemap','CData',world_map,'EdgeColor','none','FaceAlpha',0.9);
        hg = hgtransform('Parent',ax);
        set(earth,'Parent',hg);
        axis equal
        axis off
        view(-42,14);
        u=0;
        %The equtorial plane
        x_vec=linspace(-2*semi_major,2*semi_major,40);
        y_vec=linspace(-2*semi_major,2*semi_major,40);
        z_mat=zeros(40,40);
        [x_mat,y_mat]=meshgrid(x_vec,y_vec);
        surf(x_mat,y_mat,z_mat);
        for vv=1:10:length(theta)
            earth_rot=makehgtform('zrotate',theta(vv));
            pl(vv)=plot3(X(vv),Y(vv),Z(vv),'v','MarkerFaceColor','auto','MarkerSize',10);
            set(hg,'Matrix',earth_rot);
            drawnow
            u=u+1;
            if u>1
                 delete(pl(vv));
                pl(vv)=plot3(X(vv),Y(vv),Z(vv),'--mx','LineWidth',0.8,'MarkerSize',2);
            end
            if app.three_d
                app.three_d=0;
                close all;
                break;
            end
        end
        end
        figure(2)
        world_map=imread("land_ocean_ice_2048.jpg","jpg");
        image(-180:180,90:-1:-90,world_map);
        set(gca,'YDir','normal');
        axis normal
        ax=gca;
        axis([-180 180 -90 90]);
        ax.YTick=-90:15:90;
        ax.XTick=-180:20:180;
        hold on 
        axis equal
        axis tight
        grid on 
        xlabel('Longitude')
        ylabel('Latitude')
        title('Ground Track')
        if a2=="On"
            for o=1:length(longitude)
            plot(longitude(o),latitude(o),'.','ColorMode','auto');
            pause(1e-9);
            xlabel('Longitude')
             ylabel('Latitude')
             title('Ground Track')
            if app.live
                app.live=0;
                close all;
                break;
            end
            end
        else
             plot(longitude,latitude,'.w');
             text(longitude(1),latitude(1),'\leftarrow start point','Color','red','FontSize',20);
             xlabel('Longitude')
             ylabel('Latitude')
             title('Ground Track')
        end


        end

        % Button pushed function: StopButton
        function StopButtonPushed(app, event)
            if app.liveGroundTrackSwitch.Value=="On"   
                app.live=1;
            elseif app.DSwitch=="On"
                app.three_d=1;
            end
        end

        % Value changed function: PreSelectedOrbitsDropDown
        function PreSelectedOrbitsDropDownValueChanged(app, event)
            value = app.PreSelectedOrbitsDropDown.Value;
            switch value
                case "Custom"
                    app.SemimajorEditField_2.Editable="on";
                    app.SemimajorEditField_2.ValueDisplayFormat
                    app.EccentrcityEditField_2.Editable="on";
                    app.AOPEditField_2.Editable="on";
                    app.RAANEditField_2.Editable="on";
                    app.InclinationEditField_2.Editable="on";
                    app.Init_tureAnomalyEditField_2.Editable="on";
                    app.NOOrbitsEditField_2.Editable="on";
                case "Geostationary_1"
                    app.SemimajorEditField_2.Value=42158;
                    app.EccentrcityEditField_2.Value=0;
                    app.InclinationEditField_2.Value=0;
                    app.Init_tureAnomalyEditField_2.Value=0;
                    app.NOOrbitsEditField_2.Value=1;
                    app.SemimajorEditField_2.Editable="off";
                    app.EccentrcityEditField_2.Editable="off";
                    app.AOPEditField_2.Editable="off";
                    app.RAANEditField_2.Editable="on";
                    app.InclinationEditField_2.Editable="on";
                    app.Init_tureAnomalyEditField_2.Editable="on";
                    app.NOOrbitsEditField_2.Editable="on";
                case "Monlyia"
                    app.SemimajorEditField_2.Value=26571;
                    app.EccentrcityEditField_2.Value=0.74;
                    app.InclinationEditField_2.Value=0;
                    app.Init_tureAnomalyEditField_2.Value=0;
                    app.AOPEditField_2.Value=270;
                    app.NOOrbitsEditField_2.Value=1;
                    app.SemimajorEditField_2.Editable="off";
                    app.EccentrcityEditField_2.Editable="off";
                    app.AOPEditField_2.Editable="off";
                    app.RAANEditField_2.Editable="on";
                    app.InclinationEditField_2.Editable="on";
                    app.Init_tureAnomalyEditField_2.Editable="on";
                    app.NOOrbitsEditField_2.Editable="on";
                case "ISS"
                    app.SemimajorEditField_2.Value=400;
                    app.EccentrcityEditField_2.Value=0;
                    app.InclinationEditField_2.Value=51.64;
                    app.Init_tureAnomalyEditField_2.Value=0;
                    app.NOOrbitsEditField_2.Value=1;
                    app.SemimajorEditField_2.Editable="off";
                    app.EccentrcityEditField_2.Editable="off";
                    app.AOPEditField_2.Editable="off";
                    app.RAANEditField_2.Editable="on";
                    app.InclinationEditField_2.Editable="on";
                    app.Init_tureAnomalyEditField_2.Editable="on";
                    app.NOOrbitsEditField_2.Editable="on";
            end
                    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.StopButton = uibutton(app.UIFigure, 'push');
            app.StopButton.ButtonPushedFcn = createCallbackFcn(app, @StopButtonPushed, true);
            app.StopButton.Text = 'Stop';
            app.PLOTButton = uibutton(app.UIFigure, 'push');
            app.PLOTButton.ButtonPushedFcn = createCallbackFcn(app, @PLOTButtonPushed, true);
            app.PLOTButton.Text = 'PLOT';
            app.PreSelectedOrbitsDropDownLabel = uilabel(app.UIFigure);
            app.PreSelectedOrbitsDropDownLabel.Text = 'Pre-Selected Orbits';
            app.PreSelectedOrbitsDropDown = uidropdown(app.UIFigure);
            app.PreSelectedOrbitsDropDown.Items = {'Custom', 'Geostationary_1', 'Monlyia', 'ISS'};
            app.PreSelectedOrbitsDropDown.ValueChangedFcn = createCallbackFcn(app, @PreSelectedOrbitsDropDownValueChanged, true);
            app.PreSelectedOrbitsDropDown.Value = 'Custom';
            app.SemimajorEditField_2Label = uilabel(app.UIFigure);
            app.SemimajorEditField_2Label.Text = 'Semi-major';
            app.SemimajorEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.AOPEditField_2Label = uilabel(app.UIFigure);
            app.AOPEditField_2Label.Text = 'AOP';
            app.AOPEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.EccentrcityEditField_2Label = uilabel(app.UIFigure);
            app.EccentrcityEditField_2Label.Text = 'Eccentrcity';
            app.EccentrcityEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.Init_tureAnomalyEditField_2Label = uilabel(app.UIFigure);
            app.Init_tureAnomalyEditField_2Label.Text = 'Init_ture Anomaly';
            app.Init_tureAnomalyEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.RAANEditField_2Label = uilabel(app.UIFigure);
            app.RAANEditField_2Label.Text = 'RAAN';
            app.RAANEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.InclinationEditField_2Label = uilabel(app.UIFigure);
            app.InclinationEditField_2Label.Text = 'Inclination';
            app.InclinationEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.NOOrbitsEditField_2Label = uilabel(app.UIFigure);
            app.NOOrbitsEditField_2Label.Text = 'NO.Orbits';
            app.NOOrbitsEditField_2 = uieditfield(app.UIFigure, 'numeric');
            app.NOOrbitsEditField_2.Value = 1;
            app.DSwitchLabel = uilabel(app.UIFigure);
            app.DSwitchLabel.Text = '3D';
            app.DSwitch = uiswitch(app.UIFigure, 'slider');
            app.liveGroundTrackSwitchLabel = uilabel(app.UIFigure);
            app.liveGroundTrackSwitchLabel.Text = 'live Ground Track';
            app.liveGroundTrackSwitch = uiswitch(app.UIFigure, 'slider');
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end